classdef App_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        axes1         matlab.ui.control.UIAxes
        Empezar       matlab.ui.control.Button
        Segmentar     matlab.ui.control.Button
        slider1       matlab.ui.control.Slider
        Guardar       matlab.ui.control.Button
        cargar        matlab.ui.control.Button
        edit1         matlab.ui.control.EditField
        text3         matlab.ui.control.Label
        pushbutton6   matlab.ui.control.Button
        uipanel1      matlab.ui.container.Panel
        Femur_bone    matlab.ui.control.CheckBox
        Tibia_bone    matlab.ui.control.CheckBox
        Tibia_fisis   matlab.ui.control.CheckBox
        Perone_bone   matlab.ui.control.CheckBox
        Perone_fisis  matlab.ui.control.CheckBox
        Rotula        matlab.ui.control.CheckBox
        Femur_fisis   matlab.ui.control.CheckBox
        edit2         matlab.ui.control.EditField
        pushbutton8   matlab.ui.control.Button
        cambiar       matlab.ui.control.CheckBox
        pushbutton9   matlab.ui.control.Button
        Stephen       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function App_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to App (see VARARGIN)
%             
%             % Choose default command line output for App
            app.output = hObject;
            app.inicio = 0;
            app.Indice = [0 0 0 0 0 0 0 1];
            set(app.Empezar, 'Visible', 'off');
%             % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: Empezar
        function Empezar_Callback(app, event)
            
            % hObject    handle to Empezar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            app.V_preFilt = zeros(size(app.V));
            
            f1 = figure('units', 'normalize', 'outerposition',[0 0 1 1]);
            plot_MRI(app.V); title('Volumen Sin Filtros, ver donde esta el perone');
            
            
            %Todas las rodillas a rodillas derecha (en dicominfo no encontre nada)
            message = sprintf('El perone esta al principio?');
            reply = questdlg(message, 'Physis', 'Yes', 'No','No');
            
            if strcmpi(reply, 'Yes')
                app.V = flipdim(app.V,3);
            end
            
            close(f1)
            
            h = waitbar(0,'Trabajando...');
            
            % Prefiltrado
            i = 50;
            se = strel('disk',i,8);
            Test = app.V(:,:,round(size(app.V,3)/2));
            STD_inicial = std(Test(:));
            Umbral = 25;
            
            for m=1:.5:30
                Im = Test + m.*(imtophat(Test,se) - imbothat(Test,se));
                Im = medfilt2(adapthisteq(Im),[5 5]);
                Error = ((std(Im(:)) - STD_inicial)/STD_inicial)*100;
            
                if Error > Umbral
                    waitbar(1/3)
                    for ii=1:size(app.V,3)
                        Im = app.V(:,:,ii);
                        Im = Im + m.*(imtophat(Im,se) - imbothat(Im,se));
                        app.V_preFilt(:,:,ii) =  medfilt2(adapthisteq(Im),[5 5]);
                    end
            
                    break
            
                else
                    continue
                end
            end
            
            waitbar(2/3)
            
            %f1 = figure('units', 'normalize', 'outerposition',[0 0 1 1]);
            %plot_MRI(app.V_preFilt); title('Kmeans');
            %uiwait(msgbox({'caca'},'Informacion','modal'));
            %close(f1)
            
            % Kmeans
            rng(1)
            V_kmeans = kmeans_p(app.V_preFilt(:),3,size(app.V));
            
            waitbar(1)
            close(h)
            
            f1 = figure('units', 'normalize', 'outerposition',[0 0 1 1]);
            plot_MRI(V_kmeans); title('Kmeans');
            answer = inputdlg('Que cluster usar (1, 2, 3)?');
            
            if not(isempty(answer))
                Cluster = str2double(answer{1,1});
                Mask1 = logical(V_kmeans==Cluster);
                Se1 = strel('disk',1,8);
                Se2 = strel('disk',7,8);
                Maskf = imerode(Mask1,Se1);
                Maskf = imclose(Maskf,Se2);
                close(f1)
            
            else
                close(f1)
                return
            end
            
            % Aplicar FiltroG
            app.V_filt = zeros(size(app.V));
            
            alfa = 1.5;
            beta = 1.2;
            
            for k=1:size(app.V,3)
            	app.V_filt(:,:,k) = filtro_gabriel(app.V_preFilt(:,:,k), not(Maskf(:,:,k)),alfa,beta);
            end
            
            %plot_MRI(app.V_filt);
            %uiwait(msgbox('Para seguir a la siguiente filtracion solo debe pulsar OK.','Informacion','modal'));
            
            app.V_seg.vol.orig = app.V;
            app.V_seg.vol.filt = app.V_filt;
            app.V_seg.info = app.info;
            app.V_seg.mascara = zeros(size(app.V_filt));
            app.V_seg.puntos = {};
            app.V_seg.filename = app.filename;
            app.V_seg.femur.fisis = zeros(size(app.V_filt));
            app.V_seg.femur.bones = zeros(size(app.V_filt));
            app.V_seg.perone.fisis = zeros(size(app.V_filt));
            app.V_seg.perone.bones = zeros(size(app.V_filt));
            app.V_seg.tibia.fisis = zeros(size(app.V_filt));
            app.V_seg.tibia.bones = zeros(size(app.V_filt));
            app.V_seg.rotula = zeros(size(app.V_filt));
            app.Prefiltrado = 1;
            
            set(app.Empezar, 'Visible', 'off');
            
            guidata(hObject, app);
        end

        % Value changed function: Femur_bone
        function Femur_bone_Callback(app, event)
            % hObject    handle to Femur_bone (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if get(hObject,'Value')
                app.Indice(1) = 1;
            else
                app.Indice(1) = 0;
            end
            guidata(hObject,handles)
        end

        % Value changed function: Femur_fisis
        function Femur_fisis_Callback(app, event)
            % hObject    handle to Femur_fisis (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if get(hObject,'Value')
                app.Indice(2) = 1;
            else
                app.Indice(2) = 0;
            end
            guidata(hObject,handles)
        end

        % Button pushed function: Guardar
        function Guardar_Callback(app, event)
            % hObject    handle to Guardar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if app.Prefiltrado
                uiwait(msgbox('Seleccione carpeta para guardar al paciente','Guardar','modal'));
                folder_save = uigetdir();
                V_seg = app.V_seg;
                save([folder_save '/' 'Rodilla_'  app.V_seg.filename],'V_seg')
                guidata(hObject, handles);
            else
                msgbox('No hay nada para guardar aÃºn')
            end
        end

        % Value changed function: Perone_bone
        function Perone_bone_Callback(app, event)
            % hObject    handle to Perone_bone (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if get(hObject,'Value')
                app.Indice(5) = 1;
            else
                app.Indice(5) = 0;
            end
            guidata(hObject,handles)
        end

        % Value changed function: Perone_fisis
        function Perone_fisis_Callback(app, event)
            % hObject    handle to Perone_fisis (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if get(hObject,'Value')
                app.Indice(6) = 1;
            else
                app.Indice(6) = 0;
            end
            guidata(hObject,handles)
        end

        % Value changed function: Rotula
        function Rotula_Callback(app, event)
            % hObject    handle to Rotula (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if get(hObject,'Value')
                app.Indice(7) = 1;
            else
                app.Indice(7) = 0;
            end
            guidata(hObject,handles)
        end

        % Button pushed function: Segmentar
        function Segmentar_Callback(app, event)
            
            if app.Prefiltrado
            
                condicion = 1;
                if app.V_seg.check(app.v)
                     message = sprintf('ï¿½Ya realizaste esta Slide, seguro que quires hacerla denuevo?');
                     reply = questdlg(message,'Chequeo','Si','No','No');
            
                     if reply == 'No'
                         condicion = 0;
                     end
            
                end
            
                handles = segmentar(handles,condicion);
                set(app.edit1,'String','Realizado');
                app.V_seg.check(app.v) = 1;
                guidata(hObject, handles);
            
            else
                msgbox('Porfavor use EMPEZAR primero antes de Segmentar slides')
            end
        end

        % Button pushed function: Stephen
        function Stephen_Callback(app, event)
            % hObject    handle to Stephen (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            app.V_seg = Stephen_final(app.V_seg);
            
            guidata(hObject, handles);
        end

        % Value changed function: Tibia_bone
        function Tibia_bone_Callback(app, event)
            % hObject    handle to Tibia_bone (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if get(hObject,'Value')
                app.Indice(3) = 1;
            else
                app.Indice(3) = 0;
            end
            guidata(hObject,handles)
        end

        % Value changed function: Tibia_fisis
        function Tibia_fisis_Callback(app, event)
            % hObject    handle to Tibia_fisis (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if get(hObject,'Value')
                app.Indice(4) = 1;
            else
                app.Indice(4) = 0;
            end
            guidata(hObject,handles)
        end

        % Value changed function: cambiar
        function cambiar_Callback(app, event)
            % hObject    handle to cambiar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if app.inicio
            
                if get(hObject, 'Value')
                   imshow(app.V_seg.mascara(:,:,app.v),[]);
                else
                    imshow(app.V(:,:,app.v));
                end
            
            end
        end

        % Button pushed function: cargar
        function cargar_Callback(app, event)
            
            uiwait(msgbox('Seleccione LA CARPETA del paciente a analizar','Success','modal'));
            
            folder = uigetdir();
            
            if folder == 0
                return
            else
                [~,app.filename,~] = fileparts(folder);
                DIM = dir(folder);
            
                app.V=[];
            
                for p = 1:size(DIM,1)
            
                    if not(DIM(p).isdir)
                        if strcmp(DIM(p).name(end-2:end),'dcm')
                            im = imadjust(im2single(dicomread([DIM(p).folder '/' DIM(p).name])));
                            app.V(:,:,end+1) = im;
                        end
                    end
                end
            
                sigma = 0.5;
                app.V = imgaussfilt3(app.V,sigma);
                %info
                n = round(p/2);
                infor = dicominfo([DIM(n).folder '/' DIM(n).name]);
                app.info = {};
            
                if infor.PixelSpacing(1)~= infor.PixelSpacing(2)
                    uiwait(msgbox('ERROR! Avisar a Francisco y Tomas! anota que rodilla es esta','Success','modal'));
                end
            
                app.info{1,1} = infor.PixelSpacing(1);
                app.info{2,1} = infor.SliceThickness;
                app.info{3,1} = infor.PatientBirthDate;
                app.info{4,1} = infor.PatientWeight;
                app.info{5,1} = infor.PatientAge;
                app.info{6,1} = infor.PatientSex;
            
                app.V_seg.check = zeros(1,size(app.V,3));
                app.V_seg.mascara = zeros(size(app.V));
            
                set(app.slider1, 'Min', 1);
                set(app.slider1, 'Max', size(app.V,3));
                set(app.slider1, 'SliderStep', [1/(size(app.V,3)-1) , 1/(size(app.V,3)-1) ]);
                set(app.slider1, 'Value', 10);
                imshow(app.V(:,:,10))
                app.Prefiltrado = 0;
                app.v = 10;
                app.inicio = 1;
            
            
                set(app.Empezar, 'Visible', 'on');
                guidata(hObject, handles);
            end
        end

        % Button pushed function: pushbutton6
        function pushbutton6_Callback(app, event)
            % hObject    handle to pushbutton6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            clc
            close all
        end

        % Button pushed function: pushbutton8
        function pushbutton8_Callback(app, event)
            % hObject    handle to pushbutton8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % --- Executes on button press in Guardar.
            
            uiwait(msgbox('Seleccione EL ARCHIVO del paciente a TERMINAR','Success','modal'));
            [filename, pathname,~] = uigetfile();
            
            if filename == 0
                return
            else
                Load = open([pathname filename]); %todo dentro
                app.V_seg = Load.V_seg;
                app.V = app.V_seg.vol.orig;
            
                set(app.slider1, 'Min', 1);
                set(app.slider1, 'Max', size(app.V,3));
                set(app.slider1, 'SliderStep', [1/(size(app.V,3)-1) , 1/(size(app.V,3)-1) ]);
                set(app.slider1, 'Value', 10);
                imshow(app.V(:,:,10))
                app.Prefiltrado = 1;
                app.v = 10;
                app.inicio = 1;
            
                guidata(hObject, handles);
            end
        end

        % Button pushed function: pushbutton9
        function pushbutton9_Callback(app, event)
            % hObject    handle to pushbutton9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            isosurf_2(app.V_seg)
        end

        % Value changed function: slider1
        function slider1_Callback(app, event)
            % hObject    handle to slider1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            app.v = get(hObject,'Value');
            set(app.edit2, 'String',app.v)
            
            if app.inicio
                app.v = get(hObject,'Value');
            
                if get(app.cambiar,'Value')
                    imshow(app.V_seg.mascara(:,:,app.v),[]);
                else
                    imshow(app.V(:,:,app.v));
                end
            
                if app.V_seg.check(app.v)
                    set(app.edit1,'String','Realizado')
                else
                    set(app.edit1, 'String','No Realizado')
                end
                guidata(hObject, handles);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'App';
            app.axes1 = uiaxes(app.figure1);
            app.Empezar = uibutton(app.figure1, 'push');
            app.Empezar.ButtonPushedFcn = createCallbackFcn(app, @Empezar_Callback, true);
            app.Empezar.Text = 'Empezar';
            app.Segmentar = uibutton(app.figure1, 'push');
            app.Segmentar.ButtonPushedFcn = createCallbackFcn(app, @Segmentar_Callback, true);
            app.Segmentar.Text = 'Segmentar Slide';
            app.slider1 = uislider(app.figure1);
            app.slider1.Limits = [0 1];
            app.slider1.MajorTicks = [];
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1_Callback, true);
            app.slider1.MinorTicks = [];
            app.Guardar = uibutton(app.figure1, 'push');
            app.Guardar.ButtonPushedFcn = createCallbackFcn(app, @Guardar_Callback, true);
            app.Guardar.Text = 'Guardar Rodilla';
            app.cargar = uibutton(app.figure1, 'push');
            app.cargar.ButtonPushedFcn = createCallbackFcn(app, @cargar_Callback, true);
            app.cargar.Text = 'Cargar Rodilla';
            app.edit1 = uieditfield(app.figure1, 'text');
            app.edit1.Value = 'No Realizado';
            app.text3 = uilabel(app.figure1);
            app.text3.Text = 'Francisco & Tomas F.';
            app.pushbutton6 = uibutton(app.figure1, 'push');
            app.pushbutton6.ButtonPushedFcn = createCallbackFcn(app, @pushbutton6_Callback, true);
            app.pushbutton6.Text = 'Salir';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Estructuras en la slide';
            app.Femur_bone = uicheckbox(app.uipanel1);
            app.Femur_bone.ValueChangedFcn = createCallbackFcn(app, @Femur_bone_Callback, true);
            app.Femur_bone.Text = 'Femur (Hueso)';
            app.Tibia_bone = uicheckbox(app.uipanel1);
            app.Tibia_bone.ValueChangedFcn = createCallbackFcn(app, @Tibia_bone_Callback, true);
            app.Tibia_bone.Text = 'Tibia (Hueso)';
            app.Tibia_fisis = uicheckbox(app.uipanel1);
            app.Tibia_fisis.ValueChangedFcn = createCallbackFcn(app, @Tibia_fisis_Callback, true);
            app.Tibia_fisis.Text = 'Tibia (Fisis)';
            app.Perone_bone = uicheckbox(app.uipanel1);
            app.Perone_bone.ValueChangedFcn = createCallbackFcn(app, @Perone_bone_Callback, true);
            app.Perone_bone.Text = 'Perone (Hueso)';
            app.Perone_fisis = uicheckbox(app.uipanel1);
            app.Perone_fisis.ValueChangedFcn = createCallbackFcn(app, @Perone_fisis_Callback, true);
            app.Perone_fisis.Text = 'Perone (Fisis)';
            app.Rotula = uicheckbox(app.uipanel1);
            app.Rotula.ValueChangedFcn = createCallbackFcn(app, @Rotula_Callback, true);
            app.Rotula.Text = 'Rotula';
            app.Femur_fisis = uicheckbox(app.uipanel1);
            app.Femur_fisis.ValueChangedFcn = createCallbackFcn(app, @Femur_fisis_Callback, true);
            app.Femur_fisis.Text = 'Femur (Fisis)';
            app.edit2 = uieditfield(app.figure1, 'text');
            app.edit2.Value = 'Slicer numero';
            app.pushbutton8 = uibutton(app.figure1, 'push');
            app.pushbutton8.ButtonPushedFcn = createCallbackFcn(app, @pushbutton8_Callback, true);
            app.pushbutton8.Text = 'Cargar Rodilla no terminada';
            app.cambiar = uicheckbox(app.figure1);
            app.cambiar.ValueChangedFcn = createCallbackFcn(app, @cambiar_Callback, true);
            app.cambiar.Text = 'Ver Segmentaciones';
            app.pushbutton9 = uibutton(app.figure1, 'push');
            app.pushbutton9.ButtonPushedFcn = createCallbackFcn(app, @pushbutton9_Callback, true);
            app.pushbutton9.Text = '3D (sólo si está listo)';
            app.Stephen = uibutton(app.figure1, 'push');
            app.Stephen.ButtonPushedFcn = createCallbackFcn(app, @Stephen_Callback, true);
            app.Stephen.Text = 'Punto de Stephen';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @App_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end