classdef Graficar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        GraficarButton             matlab.ui.control.Button
        BetaEditFieldLabel         matlab.ui.control.Label
        BetaEditField              matlab.ui.control.NumericEditField
        AlfaEditFieldLabel         matlab.ui.control.Label
        AlfaEditField              matlab.ui.control.NumericEditField
        DiametroEditFieldLabel     matlab.ui.control.Label
        DiametroEditField          matlab.ui.control.NumericEditField
        ProfundidadEditFieldLabel  matlab.ui.control.Label
        ProfundidadEditField       matlab.ui.control.NumericEditField
        CargarRodillaButton        matlab.ui.control.Button
        DestEditFieldLabel         matlab.ui.control.Label
        DestEditField              matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Property % Description
    end
    

    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            Fisis = app.V_out.mascara ==2;
            Cortical = app.V_out.mascara ==1;
            
            alpha = app.AlfaEditField.Value;
            Beta = app.BetaEditField.Value;
            D = app.DiametroEditField.Value;
            p = app.ProfundidadEditField.Value;
            
            Cilindro = Crear_solo_cilindro2(app.V_out,alpha,Beta,D,p);
            hold on;
            p1= app.UIAxes.patch(isosurface(Fisis,0.1),'FaceColor','red','EdgeColor','none');
            p2= app.UIAxes.patch(isosurface(Cortical,0.9),'FaceColor','none','EdgeColor','blue','LineWidth',0.1,'EdgeAlpha','0.4');
            p3 = app.UIAxes.patch(isosurface(Cilindro,0.1),'FaceColor','green','EdgeColor','none');
            reducepatch(p2,0.01);
            %scatter3(Pto(1),Pto(2),Pto(3),'black','filled');
            %scatter3(Pto(2),Pto(1),Pto(3),'y','filled');
            axis off; daspect([1 1 1]);
            app.DestEditField.value = Cilindro_fx_final(app.V_out,alpha,Beta,D,p);
        end

        % Button pushed function: CargarRodillaButton
        function CargarRodillaButtonPushed(app, event)
            [file,path] = uigetfile;
            V_out = load([path file]);
            app.V_out = V_out;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.BetaEditFieldLabel = uilabel(app.UIFigure);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlfaEditFieldLabel = uilabel(app.UIFigure);
            app.AlfaEditFieldLabel.Text = 'Alfa';
            app.AlfaEditField = uieditfield(app.UIFigure, 'numeric');
            app.DiametroEditFieldLabel = uilabel(app.UIFigure);
            app.DiametroEditFieldLabel.Text = 'Diametro';
            app.DiametroEditField = uieditfield(app.UIFigure, 'numeric');
            app.DiametroEditField.Value = 5;
            app.ProfundidadEditFieldLabel = uilabel(app.UIFigure);
            app.ProfundidadEditFieldLabel.Text = 'Profundidad';
            app.ProfundidadEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProfundidadEditField.Value = 30;
            app.CargarRodillaButton = uibutton(app.UIFigure, 'push');
            app.CargarRodillaButton.ButtonPushedFcn = createCallbackFcn(app, @CargarRodillaButtonPushed, true);
            app.CargarRodillaButton.Text = 'Cargar Rodilla';
            app.DestEditFieldLabel = uilabel(app.UIFigure);
            app.DestEditFieldLabel.Text = '% Dest';
            app.DestEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Graficar

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end